classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Image                          matlab.ui.control.Image
        MainMenu                       matlab.ui.container.Panel
        RedDropDownLabel               matlab.ui.control.Label
        RedDropDown                    matlab.ui.control.DropDown
        BuscarButton                   matlab.ui.control.Button
        ClasificacinButton             matlab.ui.control.Button
        EntrenamientoButton            matlab.ui.control.Button
        TrainingMenu                   matlab.ui.container.Panel
        TasadeaprendizajeinicialEditField  matlab.ui.control.NumericEditField
        TasadeaprendizajeinicialEditFieldLabel  matlab.ui.control.Label
        NmerodepocasEditField          matlab.ui.control.NumericEditField
        NmerodepocasEditFieldLabel     matlab.ui.control.Label
        TamaodeminiloteEditField       matlab.ui.control.NumericEditField
        TamaodeminiloteEditFieldLabel  matlab.ui.control.Label
        SolverDropDown                 matlab.ui.control.DropDown
        SolverDropDownLabel            matlab.ui.control.Label
        VolverButton                   matlab.ui.control.Button
        EntrenarButton                 matlab.ui.control.Button
    end

    
    properties (Access = private)
        ModelExists = false; % Cambia en función de si el modelo seleccionado ya esta entrenado
        SelectedModel = "";
        ModelToLoad = "";
        ImdsValidation = "\imdsValidation.mat";
        AugImdsValidation = "\augimdsValidation.mat";
        ImdsTrain = "\imdsTrain.mat";
        AugImdsTrain = "\augimdsTrain.mat";
        Size = 0;
    end

    methods (Access = private)
        
        function checkModel(app)
            value = lower(erase(app.RedDropDown.Value, " "));
            app.SelectedModel = value;
            switch app.SelectedModel
                case {"googlenet", "resnet50"}
                    app.Size = 224;
                case "inceptionv3"
                    app.Size = 299;
                case {"alexnet", "squeezenet"}
                    app.Size = 227;
            end
            app.ModelToLoad = "\netTransferMonumentos" +value + ".mat";
            model = "..\ModelosEntrenados\" + value + "\netTransferMonumentos" +value + ".mat";
            disp(model);
            if exist(model,"file")
                 app.ModelExists = true;
                 app.ClasificacinButton.Enable = "on";
                 app.BuscarButton.Enable = "on";
            else
                 app.ModelExists = false;
                 app.EntrenamientoButton.Enable = "on";
                 app.ClasificacinButton.Enable = "off";
                 app.BuscarButton.Enable = "off";
            end
        end
        
        function goToMainMenu(app)
            app.MainMenu.Visible = 'on';
            app.TrainingMenu.Visible = 'off';
        end
        function goToTrainingMenu(app)
            app.MainMenu.Visible = 'off';
            app.TrainingMenu.Visible = 'on';
        end

        function disableButtons(app)
            app.RedDropDown.Enable = "off";
            app.BuscarButton.Enable = "off";
            app.ClasificacinButton.Enable = "off";
            app.EntrenamientoButton.Enable = "off";
            app.VolverButton.Enable = "off";
            app.EntrenarButton.Enable = "off";
        end

        function enableButtons(app)
            app.RedDropDown.Enable = "on";
            app.BuscarButton.Enable = "on";
            app.ClasificacinButton.Enable = "on";
            app.EntrenamientoButton.Enable = "on";
            app.VolverButton.Enable = "on";
            app.EntrenarButton.Enable = "on";
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            cd(fileparts(mfilename("fullpath")) + "\src");
            checkModel(app);
        end

        % Value changed function: RedDropDown
        function RedDropDownValueChanged(app, event)
            checkModel(app);
        end

        % Button pushed function: EntrenamientoButton
        function EntrenamientoButtonPushed(app, event)
            if app.ModelExists == true
                choice = questdlg('El modelo seleccionado ya ha sido entrenado, si continúa con el proceso se sustituirá. ¿Está seguro?', 'Confirmación', 'Sí', 'No', 'No');
                switch choice
                    case "Sí"                        
                        goToTrainingMenu(app);
                    case "No"
                end
            else
                goToTrainingMenu(app);
            end
        end

        % Button pushed function: VolverButton
        function VolverButtonPushed(app, event)
            goToMainMenu(app);
        end

        % Button pushed function: EntrenarButton
        function EntrenarButtonPushed(app, event)
            switch app.SelectedModel
                case {"googlenet", "resnet50"}
                    disableButtons(app);
                    Training224(app.SelectedModel,app.SolverDropDown.Value, app.TamaodeminiloteEditField.Value, app.NmerodepocasEditField.Value, app.TasadeaprendizajeinicialEditField.Value);
                    enableButtons(app)
                    goToMainMenu(app);
                case "inceptionv3"
                    disableButtons(app);
                    Training299(app.SelectedModel,app.SolverDropDown.Value, app.TamaodeminiloteEditField.Value, app.NmerodepocasEditField.Value, app.TasadeaprendizajeinicialEditField.Value);
                    enableButtons(app)
                    goToMainMenu(app);
                case {"alexnet", "squeezenet"}
                    disableButtons(app);
                    Training227(app.SelectedModel,app.SolverDropDown.Value, app.TamaodeminiloteEditField.Value, app.NmerodepocasEditField.Value, app.TasadeaprendizajeinicialEditField.Value);
                    enableButtons(app)
                    goToMainMenu(app);
            end
        end

        % Button pushed function: ClasificacinButton
        function ClasificacinButtonPushed(app, event)
            disableButtons(app);
            Classify(app.SelectedModel, app.ModelToLoad, app.ImdsValidation, app.AugImdsValidation, app.ImdsTrain, app.AugImdsTrain);
            enableButtons(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            respuesta = uiconfirm(app.UIFigure, '¿Estás seguro de que quieres salir?', 'Confirmar cierre', 'Options', {'Sí', 'Cancelar'}, 'DefaultOption', 'Cancelar');
            switch respuesta
                case 'Sí'
                    delete(app);
                case 'Cancelar'   
            end
        end

        % Button pushed function: BuscarButton
        function BuscarButtonPushed(app, event)
            % Abre el cuadro de diálogo para seleccionar un archivo
            [archivo, ruta] = uigetfile({'*.jpg'}, 'Seleccionar un archivo');
            rutaArchivo = fullfile(ruta, archivo);
            
            % Verifica si el usuario seleccionó un archivo
            if archivo ~= 0
                disableButtons(app);
                Search(app.SelectedModel, app.ModelToLoad, rutaArchivo, app.Size);
                enableButtons(app);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TrainingMenu = uipanel(app.UIFigure);
            app.TrainingMenu.Visible = 'off';
            app.EntrenarButton = uibutton(app.TrainingMenu, 'push');
            app.EntrenarButton.ButtonPushedFcn = createCallbackFcn(app, @EntrenarButtonPushed, true);
            app.EntrenarButton.Text = 'Entrenar';
            app.VolverButton = uibutton(app.TrainingMenu, 'push');
            app.VolverButton.ButtonPushedFcn = createCallbackFcn(app, @VolverButtonPushed, true);
            app.VolverButton.Text = 'Volver';
            app.SolverDropDownLabel = uilabel(app.TrainingMenu);
            app.SolverDropDownLabel.Text = 'Solver';
            app.SolverDropDown = uidropdown(app.TrainingMenu);
            app.SolverDropDown.Items = {'sgdm', 'adam', 'rmsprop', 'lbfgs'};
            app.SolverDropDown.Value = 'sgdm';
            app.TamaodeminiloteEditFieldLabel = uilabel(app.TrainingMenu);
            app.TamaodeminiloteEditFieldLabel.Text = 'Tamaño de minilote';
            app.TamaodeminiloteEditField = uieditfield(app.TrainingMenu, 'numeric');
            app.TamaodeminiloteEditField.Limits = [4 500];
            app.TamaodeminiloteEditField.RoundFractionalValues = 'on';
            app.TamaodeminiloteEditField.Value = 10;
            app.NmerodepocasEditFieldLabel = uilabel(app.TrainingMenu);
            app.NmerodepocasEditFieldLabel.Text = 'Número de épocas';
            app.NmerodepocasEditField = uieditfield(app.TrainingMenu, 'numeric');
            app.NmerodepocasEditField.Limits = [1 50];
            app.NmerodepocasEditField.RoundFractionalValues = 'on';
            app.NmerodepocasEditField.Value = 6;
            app.TasadeaprendizajeinicialEditFieldLabel = uilabel(app.TrainingMenu);
            app.TasadeaprendizajeinicialEditFieldLabel.Text = 'Tasa de aprendizaje  inicial';
            app.TasadeaprendizajeinicialEditField = uieditfield(app.TrainingMenu, 'numeric');
            app.TasadeaprendizajeinicialEditField.Limits = [0.0001 0.05];
            app.TasadeaprendizajeinicialEditField.Value = 0.0003;
            app.MainMenu = uipanel(app.UIFigure);
            app.EntrenamientoButton = uibutton(app.MainMenu, 'push');
            app.EntrenamientoButton.ButtonPushedFcn = createCallbackFcn(app, @EntrenamientoButtonPushed, true);
            app.EntrenamientoButton.Text = 'Entrenamiento';
            app.ClasificacinButton = uibutton(app.MainMenu, 'push');
            app.ClasificacinButton.ButtonPushedFcn = createCallbackFcn(app, @ClasificacinButtonPushed, true);
            app.ClasificacinButton.Enable = 'off';
            app.ClasificacinButton.Text = 'Clasificación';
            app.BuscarButton = uibutton(app.MainMenu, 'push');
            app.BuscarButton.ButtonPushedFcn = createCallbackFcn(app, @BuscarButtonPushed, true);
            app.BuscarButton.Enable = 'off';
            app.BuscarButton.Text = 'Buscar';
            app.RedDropDown = uidropdown(app.MainMenu);
            app.RedDropDown.Items = {'Inception v3', 'AlexNet', 'SqueezeNet', 'GoogLeNet', 'ResNet50'};
            app.RedDropDown.ValueChangedFcn = createCallbackFcn(app, @RedDropDownValueChanged, true);
            app.RedDropDown.Value = 'Inception v3';
            app.RedDropDownLabel = uilabel(app.MainMenu);
            app.RedDropDownLabel.Text = 'Red:';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Recursos', 'image.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end